classdef app3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        Panel                matlab.ui.container.Panel
        FaceMaskDetectionAndFaceRecognitionLabel  matlab.ui.control.Label
        Image3               matlab.ui.control.Image
        OutputButton         matlab.ui.control.Button
        UITable              matlab.ui.control.Table
        UploadPhotoLabel     matlab.ui.control.Label
        WebcamButton         matlab.ui.control.Button
        BrowsePictureButton  matlab.ui.control.Button
        Image2               matlab.ui.control.Image
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BrowsePictureButton
        function BrowsePictureButtonPushed(app, event)
            [filename pathname] = uigetfile({'*.jpg'},"Open file");
            fullpathname = strcat(pathname,filename);
            e = imread(fullpathname);

            load myNet1;
            load recognizer;
            faceDetector=vision.CascadeObjectDetector;
    
    M = 'nafis ';
    bboxes =step(faceDetector,e);
    if(sum(sum(bboxes))~=0)%if obj is detected
        face_count = size(bboxes);
        face_count = face_count(1);
        masked = 0;
        without_mask = 0;
        for i=1:face_count
            es=imcrop(e,bboxes(i,:));
            es=imresize(es,[227 227]);
            label=classify(myNet1,es);
            if label == "mask"
                masked = masked + 1;
                color = 'green';
            else
                without_mask = without_mask + 1;
                color = 'red';
                label=classify(recognizer, es);
            e = insertObjectAnnotation(e, "rectangle", bboxes(i, :), label, "Color", 'red');
            T(i) = label;        
           
            end
            
        end
    image(e);
   
    title(strcat("With mask: ", num2str(masked), ", without mask: ", num2str(without_mask)));
    
    else
        image(e);
        title('No Face Detected');
    end
    S = [M T];
    filename = 'unmasked.xlsx';
    writematrix (S',filename);
        end

        % Button pushed function: WebcamButton
        function WebcamButtonPushed(app, event)
            cam = webcam;
            e = cam.snapshot;

            load myNet1;
            load recognizer;
            faceDetector=vision.CascadeObjectDetector;
    M = 'nafis ';      
    bboxes =step(faceDetector,e);
    if(sum(sum(bboxes))~=0)%if obj is detected
        face_count = size(bboxes);
        face_count = face_count(1);
        masked = 0;
        without_mask = 0;
        for i=1:face_count
            es=imcrop(e,bboxes(i,:));
            es=imresize(es,[227 227]);
            label=classify(myNet1,es);
            if label == "mask"
                masked = masked + 1;
                color = 'green';
            else
              
                without_mask = without_mask + 1;
                color = 'red';
                label=classify(recognizer, es);
            e = insertObjectAnnotation(e, "rectangle", bboxes(i, :), label, "Color", 'red');
            T(i) = label;
            end
            
        end
    image(e);
    title(strcat("With mask: ", num2str(masked), ", without mask: ", num2str(without_mask)));
    
    else
        image(e);
        title('No Face Detected');
    end
     S = [M T];
    filename = 'unmasked.xlsx';
    writematrix (S',filename);
   
        end

        % Button pushed function: OutputButton
        function OutputButtonPushed(app, event)
            t = readtable('unmasked.xlsx','Range','A1:A6');
            app.UITable.Data = t;
            
        end

        % Callback function
        function RefreshButtonPushed(app, event)
         
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.Image2 = uiimage(app.Panel);
            app.Image2.ImageSource = 'Capture1.JPG';
            app.BrowsePictureButton = uibutton(app.Panel, 'push');
            app.BrowsePictureButton.ButtonPushedFcn = createCallbackFcn(app, @BrowsePictureButtonPushed, true);
            app.BrowsePictureButton.Text = 'Browse Picture';
            app.WebcamButton = uibutton(app.Panel, 'push');
            app.WebcamButton.ButtonPushedFcn = createCallbackFcn(app, @WebcamButtonPushed, true);
            app.WebcamButton.Text = 'Webcam';
            app.UploadPhotoLabel = uilabel(app.Panel);
            app.UploadPhotoLabel.Text = 'Upload Photo ';
            app.UITable = uitable(app.Panel);
            app.UITable.ColumnName = {'Unmasked Persons'};
            app.UITable.RowName = {};
            app.OutputButton = uibutton(app.Panel, 'push');
            app.OutputButton.ButtonPushedFcn = createCallbackFcn(app, @OutputButtonPushed, true);
            app.OutputButton.Text = 'Output';
            app.Image3 = uiimage(app.Panel);
            app.Image3.ImageSource = '12.png';
            app.FaceMaskDetectionAndFaceRecognitionLabel = uilabel(app.Panel);
            app.FaceMaskDetectionAndFaceRecognitionLabel.Text = 'Face-Mask Detection And Face Recognition';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end